classdef oscilloscope_keyside_measurement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Step05NamethefilePanel       matlab.ui.container.Panel
        fourthEditField              matlab.ui.control.EditField
        fourthEditFieldLabel         matlab.ui.control.Label
        thirdEditField               matlab.ui.control.EditField
        thirdEditFieldLabel          matlab.ui.control.Label
        secondEditField              matlab.ui.control.EditField
        secondEditFieldLabel         matlab.ui.control.Label
        firstEditField               matlab.ui.control.EditField
        firstEditFieldLabel          matlab.ui.control.Label
        ReadmeTextArea               matlab.ui.control.TextArea
        ReadmeTextAreaLabel          matlab.ui.control.Label
        Step1SpecifyoutputfilePanel  matlab.ui.container.Panel
        Sub1EditField                matlab.ui.control.EditField
        Sub1EditFieldLabel           matlab.ui.control.Label
        FolderPathEditField          matlab.ui.control.EditField
        FolderPathEditFieldLabel     matlab.ui.control.Label
        Step0ChoosethechannelPanel   matlab.ui.container.Panel
        ch4CheckBox                  matlab.ui.control.CheckBox
        ch3CheckBox                  matlab.ui.control.CheckBox
        ch2CheckBox                  matlab.ui.control.CheckBox
        ch1CheckBox                  matlab.ui.control.CheckBox
        RecordButton                 matlab.ui.control.Button
        ch4                          matlab.ui.control.UIAxes
        ch3                          matlab.ui.control.UIAxes
        ch2                          matlab.ui.control.UIAxes
        ch1                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.RecordButton.Enable = 'off';
            app.RecordButton.Text = 'Recording';
            a=12

            try
                %Connect the Osc
                osc = oscilloscope();
                osc.Resource = 'USB0::0x2A8D::0x0396::CN61297440::0::INSTR';
                connect(osc);
                
                %prepare the resultArray
                resultArray = [0,0,0,0];
                flag = 0 ;
    
                if app.ch1CheckBox.Value == true
                    flag = flag + 1;
                    resultArray(1) = 1;
                end
                
                if app.ch2CheckBox.Value == true
                    flag = flag + 1;
                    resultArray(2) = 1;
                end
                
                if app.ch3CheckBox.Value == true
                    flag = flag + 1;
                    resultArray(3) = 1;
                end
                
                if app.ch4CheckBox.Value == true
                    flag = flag + 1;
                    resultArray(4) = 1;
                end
    
                %prepare the folder
                base = app.FolderPathEditField.Value; 
                deg1 = app.Sub1EditField.Value; 
    
                folderName = fullfile(base, deg1); 
     
                if ~exist(folderName, 'dir')
                    mkdir(folderName);
                end
    
                %read the result & save it
                ch1name = app.firstEditField.Value;
                ch2name = app.secondEditField.Value;
                ch3name = app.thirdEditField.Value;
                ch4name = app.fourthEditField.Value;
                list = [string(ch1name), string(ch2name), string(ch3name), string(ch4name)];
    
    
                %Clear the panel
                cla(app.ch1, 'reset');
                cla(app.ch2, 'reset');
                cla(app.ch3, 'reset');
                cla(app.ch4, 'reset');
                
                
                %i am not sure how to deal with this smartly so this is 
                %my solution
                j = 1;
                switch flag
                    case 0
                        % No channels selected
                        error('No channels selected.');
    
                    case 1
                        %one channel is selected
                        q = readWaveform(osc);
                        plot(app.ch1,q)
                        save(fullfile(folderName, list(1)), 'q');
                        fig =figure;      
                        plot(q);
                        title(list(1));
                        xlabel('Samples');
                        ylabel('Voltage');
                        pngFileName = fullfile(folderName, 'combined_channels.png');
                        saveas(fig, pngFileName);
                        close(fig);
                    case 2
                        %two channels are selected
                        [q,w] = readWaveform(osc);
                        plot(app.ch1,q)
                        plot(app.ch2,w)
                        save(fullfile(folderName, list(1)), 'q');
                        save(fullfile(folderName, list(2)), 'w');
                        
                        fig =figure; 
                        subplot(2,1,1);        
                        plot(q);
                        title(list(1));
                        xlabel('Samples');
                        ylabel('Voltage');
                                 
                        subplot(2,1,2);        
                        plot( w);
                        title(list(2));
                        xlabel('Samples');
                        ylabel('Voltage');
    
                        pngFileName = fullfile(folderName, 'combined_channels.png');
                        saveas(fig, pngFileName);
                        close(fig);
                    case 3
                        [q,w,e] = readWaveform(osc);
                        plot(app.ch1,q)
                        plot(app.ch2,w)
                        plot(app.ch3,e)
                        save(fullfile(folderName, list(1)), 'q');
                        save(fullfile(folderName, list(2)), 'w');
                        save(fullfile(folderName, list(3)), 'e');
                        
                        fig =figure; 
                        subplot(3,1,1);        
                        plot(q);
                        title(list(1));
                        xlabel('Samples');
                        ylabel('Voltage');
                                 
                        subplot(3,1,2);        
                        plot( w);
                        title(list(2));
                        xlabel('Samples');
                        ylabel('Voltage');
    
                        subplot(3,1,3);        
                        plot( e);
                        title(list(3));
                        xlabel('Samples');
                        ylabel('Voltage');
    
                        pngFileName = fullfile(folderName, 'combined_channels.png');
                        saveas(fig, pngFileName);
                        close(fig);
                    case 4
                        [q,w,e,r] = readWaveform(osc);
                        plot(app.ch1,q)
                        plot(app.ch2,w)
                        plot(app.ch3,e)
                        plot(app.ch4,r)
                        save(fullfile(folderName, list(1)), 'q');
                        save(fullfile(folderName, list(2)), 'w');
                        save(fullfile(folderName, list(3)), 'e');
                        save(fullfile(folderName, list(4)), 'r');
    
                        fig =figure; 
                        subplot(4,1,1);        
                        plot(q);
                        title(list(1));
                        xlabel('Samples');
                        ylabel('Voltage');
                                 
                        subplot(4,1,2);        
                        plot( w);
                        title(list(2));
                        xlabel('Samples');
                        ylabel('Voltage');
    
                        subplot(4,1,3);        
                        plot( e);
                        title(list(3));
                        xlabel('Samples');
                        ylabel('Voltage');
    
                        subplot(4,1,4);        
                        plot( r);
                        title(list(4));
                        xlabel('Samples');
                        ylabel('Voltage');
                        pngFileName = fullfile(folderName, 'combined_channels.png');
                        saveas(fig, pngFileName);
                        close(fig);
                end
            
            catch ME
                % If an error occurs, display the error message
                disp(['An error occurred: ', ME.message]);
            end
            app.RecordButton.Enable = 'on';
            app.RecordButton.Text = 'Record';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ch1 = uiaxes(app.UIFigure);
            ylabel(app.ch1, 'voltage')
            zlabel(app.ch1, 'Z')
            app.ch2 = uiaxes(app.UIFigure);
            ylabel(app.ch2, 'voltage')
            zlabel(app.ch2, 'Z')
            app.ch3 = uiaxes(app.UIFigure);
            ylabel(app.ch3, 'voltage')
            zlabel(app.ch3, 'Z')
            app.ch4 = uiaxes(app.UIFigure);
            xlabel(app.ch4, 'sample')
            ylabel(app.ch4, 'voltage')
            zlabel(app.ch4, 'Z')
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.Step0ChoosethechannelPanel = uipanel(app.UIFigure);
            app.Step0ChoosethechannelPanel.Title = 'Step 0: Choose the channel';
            app.ch1CheckBox = uicheckbox(app.Step0ChoosethechannelPanel);
            app.ch1CheckBox.Text = 'ch1';
            app.ch1CheckBox.Value = true;
            app.ch2CheckBox = uicheckbox(app.Step0ChoosethechannelPanel);
            app.ch2CheckBox.Text = 'ch2';
            app.ch2CheckBox.Value = true;
            app.ch3CheckBox = uicheckbox(app.Step0ChoosethechannelPanel);
            app.ch3CheckBox.Text = 'ch3';
            app.ch3CheckBox.Value = true;
            app.ch4CheckBox = uicheckbox(app.Step0ChoosethechannelPanel);
            app.ch4CheckBox.Text = 'ch4';
            app.ch4CheckBox.Value = true;
            app.Step1SpecifyoutputfilePanel = uipanel(app.UIFigure);
            app.Step1SpecifyoutputfilePanel.Title = 'Step 1: Specify output file';
            app.FolderPathEditFieldLabel = uilabel(app.Step1SpecifyoutputfilePanel);
            app.FolderPathEditFieldLabel.Text = 'Folder Path';
            app.FolderPathEditField = uieditfield(app.Step1SpecifyoutputfilePanel, 'text');
            app.FolderPathEditField.Value = 'C:\Users\Dirk\Desktop';
            app.Sub1EditFieldLabel = uilabel(app.Step1SpecifyoutputfilePanel);
            app.Sub1EditFieldLabel.Text = 'Sub1';
            app.Sub1EditField = uieditfield(app.Step1SpecifyoutputfilePanel, 'text');
            app.Sub1EditField.Value = 'folder1';
            app.ReadmeTextAreaLabel = uilabel(app.UIFigure);
            app.ReadmeTextAreaLabel.Text = 'Read me';
            app.ReadmeTextArea = uitextarea(app.UIFigure);
            app.ReadmeTextArea.Value = {'Due to the stupidity of the repository of matlab, '; 'the output files are always going to labled in the '; 'ascending order of the channels enabled on the oscilloscope. '; ''; 'For example, if your osilloscope only enables'; ' ch1, ch2, and ch4, and your name the fiel '; '(a,b,c,d). This will lead to '; 'ch1 --a '; 'ch2 ---b'; 'ch4 -- c. '; ''; 'There is no ch3, ebcasue you did not choose '; 'ch3. '; ''; 'To be simple: '; ''; '1.Make sure to box the channels you choose on your oscilloscope '; ''; '2. the name will always be ascendingly assigned to the channel enabled on the oscilloscope'};
            app.Step05NamethefilePanel = uipanel(app.UIFigure);
            app.Step05NamethefilePanel.Title = 'Step 0.5: Name the file';
            app.firstEditFieldLabel = uilabel(app.Step05NamethefilePanel);
            app.firstEditFieldLabel.Text = 'first';
            app.firstEditField = uieditfield(app.Step05NamethefilePanel, 'text');
            app.firstEditField.Value = 'ch1';
            app.secondEditFieldLabel = uilabel(app.Step05NamethefilePanel);
            app.secondEditFieldLabel.Text = 'second';
            app.secondEditField = uieditfield(app.Step05NamethefilePanel, 'text');
            app.secondEditField.Value = 'ch2';
            app.thirdEditFieldLabel = uilabel(app.Step05NamethefilePanel);
            app.thirdEditFieldLabel.Text = 'third';
            app.thirdEditField = uieditfield(app.Step05NamethefilePanel, 'text');
            app.thirdEditField.Value = 'ch3';
            app.fourthEditFieldLabel = uilabel(app.Step05NamethefilePanel);
            app.fourthEditFieldLabel.Text = 'fourth';
            app.fourthEditField = uieditfield(app.Step05NamethefilePanel, 'text');
            app.fourthEditField.Value = 'ch4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oscilloscope_keyside_measurement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end